classdef VesselGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MainGrid                matlab.ui.container.GridLayout
        GridLayout31            matlab.ui.container.GridLayout
        BinarizeImagePanel      matlab.ui.container.Panel
        GridLayout16            matlab.ui.container.GridLayout
        BinMethodDropDownLabel  matlab.ui.control.Label
        BinMethodDropDown       matlab.ui.control.DropDown
        GridLayout32            matlab.ui.container.GridLayout
        nLevelsLabel            matlab.ui.control.Label
        BinMultiLevels          matlab.ui.control.NumericEditField
        GridLayout32_7          matlab.ui.container.GridLayout
        sensitivityLabel        matlab.ui.control.Label
        BinSensEdit             matlab.ui.control.NumericEditField
        ShowResultsPanel        matlab.ui.container.Panel
        AnalyseImagePanel       matlab.ui.container.Panel
        GridLayout16_2          matlab.ui.container.GridLayout
        GridLayout32_6          matlab.ui.container.GridLayout
        FindVesselsButton       matlab.ui.control.Button
        VesselAnalysisLabel     matlab.ui.control.Label
        GridLayout32_2          matlab.ui.container.GridLayout
        GridLayout32_3          matlab.ui.container.GridLayout
        GridLayout32_4          matlab.ui.container.GridLayout
        CleanSegmentedPanel     matlab.ui.container.Panel
        GridLayout16_3          matlab.ui.container.GridLayout
        GridLayout32_8          matlab.ui.container.GridLayout
        fillholesizeLabel       matlab.ui.control.Label
        MinHoleSizeEdit         matlab.ui.control.NumericEditField
        GridLayout32_9          matlab.ui.container.GridLayout
        minobjsizeLabel         matlab.ui.control.Label
        MinObjSizeEdit          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Open_App(app, PPA)
            movegui(app.UIFigure,'northwest');
            if ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
                % move down a bit so windows don't fully overlapp
                app.UIFigure.Position(2) = app.UIFigure.Position(2) - 50;
            end
            app.PPA = PPA;
            app.PPA.VesselGUI = app; % transfer handle to app
            
            app.PPA.Setup_Vessel_Figures();
            app.PPA.Apply_Vessel_Pre_Processing();
        end

        % Close request function: UIFigure
        function CloseApp(app, event)
            app.UIFigure.Visible = 'off';
            delete(app.PPA.VesselFigs.MainFig);
            app.PPA.VesselFigs = []; % delete old handles
        end

        % Value changed function: BinMethodDropDown, 
        % BinMultiLevels, BinSensEdit, MinHoleSizeEdit, 
        % MinObjSizeEdit
        function Apply_Vessel_Pre_Processing(app, event)
            switch app.BinMethodDropDown.Value
                case 'Otsu'
                    app.BinSensEdit.Enable = false;
                    app.BinMultiLevels.Enable = false;
                    
                case 'Multi'
                    app.BinSensEdit.Enable = false;
                    app.BinMultiLevels.Enable = true;
                    
                case 'Adaptive'
                    app.BinSensEdit.Enable = true;
                    app.BinMultiLevels.Enable = false;
                case 'Manual'
                    app.BinSensEdit.Enable = true;
                    app.BinMultiLevels.Enable = false;
            end
            app.PPA.Apply_Vessel_Pre_Processing();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Vessel';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.GridLayout31 = uigridlayout(app.MainGrid);
            app.BinarizeImagePanel = uipanel(app.GridLayout31);
            app.BinarizeImagePanel.Title = 'Binarize Image';
            app.GridLayout16 = uigridlayout(app.BinarizeImagePanel);
            app.BinMethodDropDownLabel = uilabel(app.GridLayout16);
            app.BinMethodDropDownLabel.Text = 'Bin Method';
            app.BinMethodDropDown = uidropdown(app.GridLayout16);
            app.BinMethodDropDown.Items = {'Otsu', 'Manual', 'Multi', 'Adaptive'};
            app.BinMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Pre_Processing, true);
            app.BinMethodDropDown.Value = 'Adaptive';
            app.GridLayout32 = uigridlayout(app.GridLayout16);
            app.nLevelsLabel = uilabel(app.GridLayout32);
            app.nLevelsLabel.Enable = 'off';
            app.nLevelsLabel.Text = 'n Levels';
            app.BinMultiLevels = uieditfield(app.GridLayout32, 'numeric');
            app.BinMultiLevels.Limits = [1 Inf];
            app.BinMultiLevels.RoundFractionalValues = 'on';
            app.BinMultiLevels.ValueDisplayFormat = '%i';
            app.BinMultiLevels.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Pre_Processing, true);
            app.BinMultiLevels.Enable = 'off';
            app.BinMultiLevels.Value = 1;
            app.GridLayout32_7 = uigridlayout(app.GridLayout16);
            app.sensitivityLabel = uilabel(app.GridLayout32_7);
            app.sensitivityLabel.Text = 'sensitivity';
            app.BinSensEdit = uieditfield(app.GridLayout32_7, 'numeric');
            app.BinSensEdit.Limits = [0 1];
            app.BinSensEdit.ValueDisplayFormat = '%3.2f';
            app.BinSensEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Pre_Processing, true);
            app.BinSensEdit.Value = 0.25;
            app.ShowResultsPanel = uipanel(app.GridLayout31);
            app.ShowResultsPanel.Title = 'Show Results';
            app.AnalyseImagePanel = uipanel(app.GridLayout31);
            app.AnalyseImagePanel.Title = 'Analyse Image';
            app.GridLayout16_2 = uigridlayout(app.AnalyseImagePanel);
            app.GridLayout32_6 = uigridlayout(app.GridLayout16_2);
            app.FindVesselsButton = uibutton(app.GridLayout16_2, 'push');
            app.FindVesselsButton.Text = 'Find Vessels';
            app.VesselAnalysisLabel = uilabel(app.GridLayout31);
            app.VesselAnalysisLabel.Text = 'Vessel Analysis';
            app.GridLayout32_2 = uigridlayout(app.GridLayout31);
            app.GridLayout32_3 = uigridlayout(app.GridLayout32_2);
            app.GridLayout32_4 = uigridlayout(app.GridLayout32_2);
            app.CleanSegmentedPanel = uipanel(app.GridLayout31);
            app.CleanSegmentedPanel.Title = 'Clean Segmented';
            app.GridLayout16_3 = uigridlayout(app.CleanSegmentedPanel);
            app.GridLayout32_8 = uigridlayout(app.GridLayout16_3);
            app.fillholesizeLabel = uilabel(app.GridLayout32_8);
            app.fillholesizeLabel.Text = 'fill hole size';
            app.MinHoleSizeEdit = uieditfield(app.GridLayout32_8, 'numeric');
            app.MinHoleSizeEdit.Limits = [1 Inf];
            app.MinHoleSizeEdit.RoundFractionalValues = 'on';
            app.MinHoleSizeEdit.ValueDisplayFormat = '%i';
            app.MinHoleSizeEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Pre_Processing, true);
            app.MinHoleSizeEdit.Tooltip = {'Holes of dark Pixels sorrounded by bright pixels will be filled.'};
            app.MinHoleSizeEdit.Value = 1;
            app.GridLayout32_9 = uigridlayout(app.GridLayout16_3);
            app.minobjsizeLabel = uilabel(app.GridLayout32_9);
            app.minobjsizeLabel.Text = 'min obj size';
            app.MinObjSizeEdit = uieditfield(app.GridLayout32_9, 'numeric');
            app.MinObjSizeEdit.Limits = [1 Inf];
            app.MinObjSizeEdit.RoundFractionalValues = 'on';
            app.MinObjSizeEdit.ValueDisplayFormat = '%i';
            app.MinObjSizeEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Pre_Processing, true);
            app.MinObjSizeEdit.Tooltip = {'Connected pixels with a total size smaller than this will be removed. '};
            app.MinObjSizeEdit.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VesselGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Open_App(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end